classdef ihm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IHMappUIFigure                matlab.ui.Figure
        SeuillagePanel                matlab.ui.container.Panel
        Slider_mNB                    matlab.ui.control.Slider
        Slider                        matlab.ui.control.Slider
        MultiseuilsRGBButton          matlab.ui.control.Button
        MultiseuilsNBButton           matlab.ui.control.Button
        BinarisationButton            matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        AprsButton                    matlab.ui.control.Button
        AvantButton                   matlab.ui.control.Button
        FiltragePanel                 matlab.ui.container.Panel
        Filtre                        matlab.ui.control.DropDown
        Bruit                         matlab.ui.control.DropDown
        ContoursPanel                 matlab.ui.container.Panel
        Sobel                         matlab.ui.control.DropDown
        Prewitt                       matlab.ui.control.DropDown
        ContrastesPanel               matlab.ui.container.Panel
        EgalisationButton             matlab.ui.control.Button
        TransformationLineaireButton  matlab.ui.control.Button
        OuverturedimageButton         matlab.ui.control.Button
        hist_apres                    matlab.ui.control.UIAxes
        hist_avant                    matlab.ui.control.UIAxes
        hist_avant_rgb                matlab.ui.control.UIAxes
        figure_apres                  matlab.ui.control.UIAxes
        image_originale               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)


        function h = histogramme(~, img)
            h = zeros(256, 1);
            [nl, nc] = size(img);
            for i = 1:nl
                for j = 1:nc
                    val = img(i, j) + 1;
                    h(val) = h(val) + 1;
                end
            end
        end
        
        function IM2 = transf_lineair(~, img)
            ma = max(max(max(img)));
            ma = double(ma);
            mi = min(min(min(img)));
            mi = double(mi);
        
            alpha = double((255/(ma-mi)));
            alpha = double(alpha);
            beta = double(((-255*mi)/(ma-mi)));
            beta = double(beta);
            IM2 = round(alpha.*img + beta);
        end
        
        function IM2 = egalisation(~, img, h)
            P = h./sum(h);
            S = zeros(length(P), 1);
            S(1) = P(1);
            ma = max(max(max(img)));
            ma = double(ma);
            
            for i = 2:ma + 1
                S(i) = P(i) + S(i - 1);
            end
            for i = 2:ma
                if P(i - 1) == 0
                    S(i - 1) = 0;
                end
            end
            CN = round(S.*255);
        
            [nl, nc] = size(img);
            IM2 = zeros(nl, nc);
        
            for i = 1:nl
                for j = 1:nc
                    IM2(i, j) = CN(img(i, j) + 1);
                end
            end
            IM2 = uint8(IM2);
        end
        
        function initModificationHistoric(~)
            
            IM = evalin('base', "IM");
            
            modificationHistoric = [];
            MHCursor = 0;
            MHSize = 0;
            [imageSize,lol] = size(IM);
            
            assignin("base", "imageSize", imageSize);
            assignin("base", "modificationHistoric", modificationHistoric)
            assignin("base", "MHCursor", MHCursor)
            assignin("base", "MHSize", MHSize)        
            
        end
        
        function addModification(~, img)
            
            modificationHistoric = evalin('base', "modificationHistoric");
            MHCursor = evalin('base', "MHCursor");
            MHSize = evalin('base', "MHSize");
            
            if MHCursor == MHSize %Need to append image in array
               modificationHistoric =[modificationHistoric;img];
               MHCursor = MHCursor + 1;
               MHSize = MHSize + 1; 
               assignin("base", "modificationHistoric", modificationHistoric)
            else % write in existing cell and delete old/deprecated next modifications
               MHCursor = MHCursor + 1;
               imageSize = evalin("base", "imageSize");
               images = cat(MHSize,modificationHistoric);
               left = (imageSize - 1) * (MHCursor - 1) + 1;
               right = (imageSize - 1) * (MHCursor) + 1;
               images((left:right),:,:) = img;
               imagesReduced = images((1:right),:,:);
               MHSize = MHCursor;
               assignin("base", "modificationHistoric", imagesReduced)
            end
            assignin("base", "MHCursor", MHCursor)
            assignin("base", "MHSize", MHSize)
           
        end
        
        function img = ctrlYMH(~)
            modificationHistoric = evalin('base', "modificationHistoric");
            MHCursor = evalin('base', "MHCursor");
            MHSize = evalin('base', "MHSize");
            imageSize = evalin("base", "imageSize");
          
            images = cat(MHSize,modificationHistoric);
            if (MHCursor < MHSize)
                MHCursor = MHCursor + 1;
            end
            
            % Don't place that in pre-block
            % It's usefull when no modification has been applied
            left = (imageSize - 1) * (MHCursor - 1) + 1;
            right = (imageSize - 1) * (MHCursor) + 1;
            img = images((left:right),:,:);
            assignin("base", "MHCursor", MHCursor)
        end
        
        function img = ctrlZMH(~)
            modificationHistoric = evalin('base', "modificationHistoric");
            MHCursor = evalin('base', "MHCursor");
            MHSize = evalin('base', "MHSize");
            imageSize = evalin("base", "imageSize");
            
            images = cat(MHSize,modificationHistoric);
            if MHCursor == 1
                img = images((1:(imageSize - 1)),:,:);
            else
                MHCursor = MHCursor - 1;
                left = (imageSize - 1) * (MHCursor - 1) + 1;
                right = (imageSize - 1) * (MHCursor) + 1;
                img = images((left:right),:,:);
                assignin("base", "MHCursor", MHCursor)
            end
            
        end
        
        function IM = binarisation(~, img, val)
            [nl, nc] = size(img);
            for i = 1:nl
                for j = 1:nc
                    if img(i, j) > val
                        IM(i, j) = 1;
                    else
                        IM(i, j) = 0;
                    end
                end
            end
            IM = IM.*255;
        end
        
        function IM = multiseuillage_NB(~, img, nNB)
            [nl, nc] = size(img);
            nNB = round(nNB);
            nPixelSeuil = 255/nNB;
            
            newVal = zeros(nNB, 1);
            newVal(1) = 0;
            for i = 2:nNB
                newVal(i) = 255/(nNB*i-1);
            end
            
            for i = 1:nl
                for j = 1:nc
                    if 0 < img(i, j) && img(i, j) < nPixelSeuil
                        IM(i, j) = newVal(1);
                    end
                    for k = 1:nNB
                        if nPixelSeuil*k < img(i, j) && img(i, j) < nPixelSeuil*2*k
                            IM(i, j) = newVal(k+1);
                        end
                    end
                end
            end
            IM = round(IM);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: OuverturedimageButton, image_originale
        function OuverturedimageButtonPushed(app, event)
            clc; close all
            name_file=uigetfile( '*.*');
            [filepath, name, ext] = fileparts(name_file);
            if ext == ".img"
                Fid = fopen(name_file);
                L = 1000;
                C = 1000;
                B = 3;
%                 L = str2double(L);
%                 B = str2double(B);
%                 C = str2double(C);
                donnees = fread(Fid, C * L * B,'*ubit8');
                fclose(Fid);
                donnees = reshape(donnees, C, L, B);
                donnees(:,:,1)=donnees(:,:,1)';
                donnees(:,:,2)=donnees(:,:,2)';
                donnees(:,:,3)=donnees(:,:,3)';
                IM(:,:,1)=donnees(:,:,3);
                IM(:,:,2)=donnees(:,:,2);
                IM(:,:,3)=donnees(:,:,1);
            else
                IM = imread(name_file);
            end
            
            imshow(IM, 'Parent', app.image_originale)
            assignin("base", "IM", IM)
            
            imgNB = im2gray(IM);
            hNB = histogramme(app,imgNB);
            app.hist_avant.Visible = "on";
            stem(app.hist_avant, hNB, '.')
            
            hR = histogramme(app, IM(:, :, 1));
            hG = histogramme(app, IM(:, :, 2));
            hB = histogramme(app, IM(:, :, 3));
            
            app.hist_avant_rgb.Visible = "on";
            stem(app.hist_avant_rgb, hR, '.', 'r')
            hold(app.hist_avant_rgb, 'on')
            stem(app.hist_avant_rgb, hG, '.', 'g')
            stem(app.hist_avant_rgb, hB, '.', 'b')
            hold(app.hist_avant_rgb, 'off')
            
%             initModificationHistoric(app);
%             addModification(app, IM);
        end

        % Button pushed function: TransformationLineaireButton
        function TransformationLineaireButtonPushed(app, event)
            img = evalin('base', "IM");
            NewImg(:, :, 1) = transf_lineair(app, img(:, :, 1));
            NewImg(:, :, 2) = transf_lineair(app, img(:, :, 2));
            NewImg(:, :, 3) = transf_lineair(app, img(:, :, 3));
            imshow(NewImg, 'Parent', app.figure_apres)
            
            hR = histogramme(app, NewImg(:, :, 1));
            hG = histogramme(app, NewImg(:, :, 2));
            hB = histogramme(app, NewImg(:, :, 3));
            
            app.hist_apres.Visible = "on";
            stem(app.hist_apres, hR, '.', 'r')
            hold(app.hist_apres, 'on')
            stem(app.hist_apres, hG, '.', 'g')
            stem(app.hist_apres, hB, '.', 'b')
            hold(app.hist_apres, 'off')
            
            assignin("base", "IM", NewImg)
            assignin("base", "hR", hR)
            assignin("base", "hG", hG)
            assignin("base", "hB", hB)
            
%             addModification(app, NewImg);
        end

        % Button pushed function: EgalisationButton
        function EgalisationButtonPushed(app, event)
            img = evalin("base", "IM");
            hR = evalin("base", "hR");
            hG = evalin("base", "hG");
            hB = evalin("base", "hB");
            
            IM2(:, :, 1) = egalisation(app, img(:, :, 1), hR);
            IM2(:, :, 2) = egalisation(app, img(:, :, 2), hG);
            IM2(:, :, 3) = egalisation(app, img(:, :, 3), hB);
            
            imshow(IM2, 'Parent', app.figure_apres)
            
            nhR = histogramme(app, IM2(:, :, 1));
            nhG = histogramme(app, IM2(:, :, 2));
            nhB = histogramme(app, IM2(:, :, 3));
            
            stem(app.hist_apres, nhR, '.', 'r')
            hold(app.hist_apres, 'on')
            stem(app.hist_apres, nhG, '.', 'g')
            stem(app.hist_apres, nhB, '.', 'b')
            hold(app.hist_apres, 'off')
            
            assignin("base", "IM", IM2)
            
%             addModification(app, IM2);
        end

        % Value changed function: Bruit
        function BruitValueChanged(app, event)
            value = app.Bruit.Value;
            im = evalin("base", "IM");
            if value == "Bruit"
                IM = im;
%                 fil = 1;
            elseif value == "Gaussien"
%                 T = 5;
%                 for i = 1:T
%                     for j = 1:T
%                         fil(i, j) = exp(-(i^2+j^2)/(2*sigma^2));
%                     end
%                 end
%                 fil = (1/(2*pi*sigma)).*fil;
                IM = imnoise(im, "gaussian");
            elseif value == "Poisson"
%                 T = 20;
%                 lambda = 10;
%                 for i = 1:T
%                     for j = 1:T
%                         fil(i, j) = exp(-lambda)*(lambda^T/factorial(T));
%                     end
%                 end
                IM = imnoise(im, "poisson");
            elseif value == "Poivre et Sel"
                IM = imnoise(im, "salt & pepper");
            end
            IM = uint8(IM);
            imshow(IM, 'Parent', app.figure_apres)
            assignin("base", "IM", IM)
            
%             addModification(app, IM);
        end

        % Value changed function: Prewitt
        function PrewittValueChanged(app, event)
            value = app.Prewitt.Value;
            im = evalin("base", "IM");
            im = rgb2gray(im);
            c = 1;
            Hs1 = [1 c 1; 0 0 0; -1 -c -1];
            Hs2 = [1 0 -1; c 0 -c; 1 0 -1];
            IMvert = conv2(im, Hs1);
            IMvert = uint8(IMvert);
            IMhor = conv2(im, Hs2);
            IMhor = uint8(IMhor);
            IMmix = abs(IMvert) + abs(IMhor);
            IMmix = uint8(IMmix);   
            if value == "Prewitt"
                imshow(im, 'Parent', app.figure_apres)
            elseif value == "Vertical"
                imshow(IMvert, 'Parent', app.figure_apres)
%                 addModification(app, IMvert)
            elseif value == "Horizontal"
                imshow(IMhor, 'Parent', app.figure_apres)
%                 addModification(app, IMhor)
            elseif value == "Mixte"
                imshow(IMmix, 'Parent', app.figure_apres)
%                 addModification(app, IMmix)
            end
            cla(app.hist_apres)
            app.hist_apres.Visible = "off";
            
        end

        % Value changed function: Sobel
        function SobelValueChanged(app, event)
            value = app.Sobel.Value;
            im = evalin("base", "IM");
            im = rgb2gray(im);
            c = 2;
            Hs1 = [1 c 1; 0 0 0; -1 -c -1];
            Hs2 = [1 0 -1; c 0 -c; 1 0 -1];
            IMvert = conv2(im, Hs1);
            IMvert = uint8(IMvert);
            IMhor = conv2(im, Hs2);
            IMhor = uint8(IMhor);
            IMmix = abs(IMvert) + abs(IMhor);
            IMmix = uint8(IMmix);
            if value == "Sobel"
                imshow(im, 'Parent', app.figure_apres)
            elseif value == "Vertical"
                imshow(IMvert, 'Parent', app.figure_apres)
%                 addModification(app, IMvert)
            elseif value == "Horizontal"
                imshow(IMhor, 'Parent', app.figure_apres)
%                 addModification(app, IMhor)
            elseif value == "Mixte"
                imshow(IMmix, 'Parent', app.figure_apres)
%                 addModification(app, IMmix)
            end
            cla(app.hist_apres)
            app.hist_apres.Visible = "off";
        end

        % Value changed function: Filtre
        function FiltreValueChanged(app, event)
            value = app.Filtre.Value;
            img = evalin("base", "IM");
            T = 3;
            M = 5;
            N = 1;
            L = M*N;
            if value == "Filtre"
                IM = img;
            elseif value == "Moyenneur"
                for i = 1:T
                    for j = 1:T
                        fil(i, j) = 1/T^2;
                    end
                end
                IM(:, :, 1) = conv2(img(:, :, 1), fil);
                IM(:, :, 2) = conv2(img(:, :, 2), fil);
                IM(:, :, 3) = conv2(img(:, :, 3), fil);
            elseif value == "Médian"
                for i = 1:L
                    if i == (L+1)/2
                        b(i) = 1;
                    else
                        b(i) = 0;
                    end
                end
                IM(:, :, 1) = conv2(img(:, :, 1), b);
                IM(:, :, 2) = conv2(img(:, :, 2), b);
                IM(:, :, 3) = conv2(img(:, :, 3), b);
            elseif value == "Milieu"
                for i = 1:L
                    if i == 1 || i == L
                        b(i) = 1/2;
                    else
                        b(i) = 0;
                    end
                end
                IM(:, :, 1) = conv2(img(:, :, 1), b);
                IM(:, :, 2) = conv2(img(:, :, 2), b);
                IM(:, :, 3) = conv2(img(:, :, 3), b);
            else
                IMG = fft2(img(:,:,1));
                H = 1/(2*pi*0.25);
                IM(:,:,1) = imfilter(IMG, H);
                IM(:,:,1) = ifft2(IM(:,:,1));
                IM(:,:,1) = abs(IM(:,:,1));
                
                IMG = fft2(img(:,:,2));
                H = 1/(2*pi*0.25);
                IM(:,:,2) = imfilter(IMG, H);
                IM(:,:,2) = ifft2(IM(:,:,2));
                IM(:,:,2) = abs(IM(:,:,2));
                
                IMG = fft2(img(:,:,3));
                H = 1/(2*pi*0.25);
                IM(:,:,3) = imfilter(IMG, H);
                IM(:,:,3) = ifft2(IM(:,:,3));
                IM(:,:,3) = abs(IM(:,:,3));
            end
        
            IM = uint8(IM);
            imshow(IM, 'Parent', app.figure_apres)
            assignin("base", "IM", IM)
%             addModification(app, IM);
        end

        % Button pushed function: AvantButton
        function AvantButtonPushed(app, event)
            IM = ctrlZMH(app);
            
            imshow(IM, "Parent", app.figure_apres)
        end

        % Button pushed function: AprsButton
        function AprsButtonPushed(app, event)
            IM = ctrlYMH(app);
            
            imshow(IM, "Parent", app.figure_apres)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imsave(app.figure_apres)
        end

        % Button pushed function: BinarisationButton
        function BinarisationButtonPushed(app, event)
                app.Slider.Visible = "on";
                app.Slider.Enable = "on";
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            img = evalin("base", "IM");
            seuil = value;
            
            IMB = binarisation(app, img(:,:,1), seuil);
            
            imshow(IMB, "Parent", app.figure_apres)
            
            hbin = histogramme(app, IMB);
            stem(app.hist_apres, hbin, '.')
        end

        % Button pushed function: MultiseuilsNBButton
        function MultiseuilsNBButtonPushed(app, event)
            app.Slider.Visible = "off";
            app.Slider.Enable = "off";
            app.Slider_mNB.Visible = "on";
            app.Slider_mNB.Enable = "on";
        end

        % Value changed function: Slider_mNB
        function Slider_mNBValueChanged(app, event)
            value = app.Slider_mNB.Value;
            img = evalin("base", "IM");
            img = im2gray(img);
            seuil = value;
            
            IMB = multiseuillage_NB(app, img, seuil);
            imshow(IMB, "Parent", app.figure_apres)
            
            hbin = histogramme(app, IMB);
            stem(app.hist_apres, hbin, '.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IHMappUIFigure = uifigure('Visible', 'off');
            app.IHMappUIFigure.Name = 'IHM app';
            app.image_originale = uiaxes(app.IHMappUIFigure);
            app.image_originale.XTick = [];
            app.image_originale.YTick = [];
            app.image_originale.ZTick = [];
            app.image_originale.Visible = 'off';
            app.image_originale.ButtonDownFcn = createCallbackFcn(app, @OuverturedimageButtonPushed, true);
            app.figure_apres = uiaxes(app.IHMappUIFigure);
            app.figure_apres.XTick = [];
            app.figure_apres.YTick = [];
            app.figure_apres.ZTick = [];
            app.figure_apres.Visible = 'off';
            app.hist_avant_rgb = uiaxes(app.IHMappUIFigure);
            app.hist_avant_rgb.XLim = [0 255];
            app.hist_avant_rgb.XTick = [0 255];
            app.hist_avant_rgb.ZTick = [];
            app.hist_avant_rgb.Visible = 'off';
            app.hist_avant = uiaxes(app.IHMappUIFigure);
            app.hist_avant.XLim = [0 255];
            app.hist_avant.XTick = [0 255];
            app.hist_avant.Visible = 'off';
            app.hist_apres = uiaxes(app.IHMappUIFigure);
            app.hist_apres.XLim = [0 256];
            app.hist_apres.XTick = [0 256];
            app.hist_apres.XTickLabel = {'0'; '255'};
            app.hist_apres.Visible = 'off';
            app.OuverturedimageButton = uibutton(app.IHMappUIFigure, 'push');
            app.OuverturedimageButton.ButtonPushedFcn = createCallbackFcn(app, @OuverturedimageButtonPushed, true);
            app.OuverturedimageButton.Text = 'Ouverture d''image';
            app.ContrastesPanel = uipanel(app.IHMappUIFigure);
            app.ContrastesPanel.Title = 'Contrastes';
            app.TransformationLineaireButton = uibutton(app.ContrastesPanel, 'push');
            app.TransformationLineaireButton.ButtonPushedFcn = createCallbackFcn(app, @TransformationLineaireButtonPushed, true);
            app.TransformationLineaireButton.Text = 'Transformation Lineaire';
            app.EgalisationButton = uibutton(app.ContrastesPanel, 'push');
            app.EgalisationButton.ButtonPushedFcn = createCallbackFcn(app, @EgalisationButtonPushed, true);
            app.EgalisationButton.Text = 'Egalisation';
            app.ContoursPanel = uipanel(app.IHMappUIFigure);
            app.ContoursPanel.Title = 'Contours';
            app.Prewitt = uidropdown(app.ContoursPanel);
            app.Prewitt.Items = {'Prewitt', 'Horizontal', 'Vertical', 'Mixte'};
            app.Prewitt.ValueChangedFcn = createCallbackFcn(app, @PrewittValueChanged, true);
            app.Prewitt.Value = 'Prewitt';
            app.Sobel = uidropdown(app.ContoursPanel);
            app.Sobel.Items = {'Sobel', 'Horizontal', 'Vertical', 'Mixte'};
            app.Sobel.ValueChangedFcn = createCallbackFcn(app, @SobelValueChanged, true);
            app.Sobel.Value = 'Sobel';
            app.FiltragePanel = uipanel(app.IHMappUIFigure);
            app.FiltragePanel.Title = 'Filtrage';
            app.Bruit = uidropdown(app.FiltragePanel);
            app.Bruit.Items = {'Bruit', 'Gaussien', 'Poisson', 'Poivre et sel'};
            app.Bruit.ValueChangedFcn = createCallbackFcn(app, @BruitValueChanged, true);
            app.Bruit.Value = 'Bruit';
            app.Filtre = uidropdown(app.FiltragePanel);
            app.Filtre.Items = {'Filtre', 'Moyenneur', 'Médian', 'Milieu', 'Fourier'};
            app.Filtre.ValueChangedFcn = createCallbackFcn(app, @FiltreValueChanged, true);
            app.Filtre.Value = 'Filtre';
            app.AvantButton = uibutton(app.IHMappUIFigure, 'push');
            app.AvantButton.ButtonPushedFcn = createCallbackFcn(app, @AvantButtonPushed, true);
            app.AvantButton.Text = {'Avant'; ''};
            app.AprsButton = uibutton(app.IHMappUIFigure, 'push');
            app.AprsButton.ButtonPushedFcn = createCallbackFcn(app, @AprsButtonPushed, true);
            app.AprsButton.Text = 'Après';
            app.SaveButton = uibutton(app.IHMappUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SeuillagePanel = uipanel(app.IHMappUIFigure);
            app.SeuillagePanel.Title = 'Seuillage';
            app.BinarisationButton = uibutton(app.SeuillagePanel, 'push');
            app.BinarisationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarisationButtonPushed, true);
            app.BinarisationButton.Text = 'Binarisation';
            app.MultiseuilsNBButton = uibutton(app.SeuillagePanel, 'push');
            app.MultiseuilsNBButton.ButtonPushedFcn = createCallbackFcn(app, @MultiseuilsNBButtonPushed, true);
            app.MultiseuilsNBButton.Text = 'Multiseuils NB';
            app.MultiseuilsRGBButton = uibutton(app.SeuillagePanel, 'push');
            app.MultiseuilsRGBButton.Text = 'Multiseuils RGB';
            app.Slider = uislider(app.SeuillagePanel);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.Slider_mNB = uislider(app.SeuillagePanel);
            app.Slider_mNB.Limits = [0 255];
            app.Slider_mNB.ValueChangedFcn = createCallbackFcn(app, @Slider_mNBValueChanged, true);
            app.Slider_mNB.Enable = 'off';
            app.Slider_mNB.Visible = 'off';
            % Show the figure after all components are created
            app.IHMappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IHMappUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IHMappUIFigure)
        end
    end
end